classdef EmocromoAnalyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        EsitoTextArea             matlab.ui.control.TextArea
        HCTTextArea               matlab.ui.control.TextArea
        AnomaliaTextArea          matlab.ui.control.TextArea
        MCVUnitLabel              matlab.ui.control.Label
        MCHCUnitLabel             matlab.ui.control.Label
        MCHUnitLabel              matlab.ui.control.Label
        StoricoSQLitePanel        matlab.ui.container.Panel
        UITable                   matlab.ui.control.Table
        DatiPazienteedEsamePanel  matlab.ui.container.Panel
        EtaUnitLabel              matlab.ui.control.Label
        PLTUnitLabel              matlab.ui.control.Label
        WBCUnitLabel              matlab.ui.control.Label
        RBCUnitLabel              matlab.ui.control.Label
        HGBUnitLabel              matlab.ui.control.Label
        MCHCEditField             matlab.ui.control.NumericEditField
        MCHCEditFieldLabel        matlab.ui.control.Label
        MCVEditField              matlab.ui.control.NumericEditField
        MCVEditFieldLabel         matlab.ui.control.Label
        MCHEditField              matlab.ui.control.NumericEditField
        MCHEditFieldLabel         matlab.ui.control.Label
        EtaEditField              matlab.ui.control.NumericEditField
        EtaEditFieldLabel         matlab.ui.control.Label
        AnalizzaeSalvaButton      matlab.ui.control.Button
        PLTEditField              matlab.ui.control.NumericEditField
        PLTEditFieldLabel         matlab.ui.control.Label
        WBCEditField              matlab.ui.control.NumericEditField
        WBCEditFieldLabel         matlab.ui.control.Label
        RBCEditField              matlab.ui.control.NumericEditField
        RBCEditFieldLabel         matlab.ui.control.Label
        HGBEditField              matlab.ui.control.NumericEditField
        HGBEditFieldLabel         matlab.ui.control.Label
        SessoDropDown             matlab.ui.control.DropDown
        SessoDropDownLabel        matlab.ui.control.Label
        DiagnosieGraficiPanel     matlab.ui.container.Panel
        EsitoLabel                matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AnalizzaeSalvaButton.Enable = 'off';
            app.EsitoLabel.Text = 'Inserire tutti i valori richiesti.';
            app.EsitoLabel.FontColor = [0.45 0.45 0.45];

            app.EtaUnitLabel.Text = 'anni';
            app.HGBUnitLabel.Text = 'g/dL';
            app.RBCUnitLabel.Text = '10^6/µL';
            app.WBCUnitLabel.Text = 'cellule/µL';
            app.PLTUnitLabel.Text = 'piastrine/µL';
            app.MCVUnitLabel.Text = 'fL';
            app.MCHUnitLabel.Text = 'pg';
            app.MCHCUnitLabel.Text = 'g/dL';

            app.refreshHistory();
            app.updateUIValidation();
        end

        function refreshHistory(app)

     try
        conn = sqlite('emocromo.db');

        sqlQuery = ['SELECT P.Codice, P.Sesso, ' ...
                    'E.HGB, E.RBC, E.WBC, E.PLT, ' ...
                    'R.Esito_Diagnostico AS Diagnosi ' ...
                    'FROM PAZIENTE P ' ...
                    'LEFT JOIN ESAME_EMOCROMO E ON P.ID_Paziente = E.ID_Paziente ' ...
                    'LEFT JOIN REFERTO_ANALISI R ON E.ID_Esame = R.ID_Esame ' ...
                    'ORDER BY P.ID_Paziente DESC LIMIT 100;'];

                dati = fetch(conn, sqlQuery);
                close(conn);

                app.UITable.Data = dati;
                app.UITable.ColumnName = ...
                    {'Codice', 'Sesso', 'HGB', 'RBC', 'WBC', 'PLT', 'Diagnosi'};
    catch
        app.UITable.Data = [];
        app.UITable.ColumnName = {'Codice', 'Sesso', 'HGB', 'RBC', 'WBC', 'PLT', 'Diagnosi'};
    end
        end

        function updateUIValidation(app)
            [ok, messaggio] = validateAppInputs(app);

            if ok
                app.AnalizzaeSalvaButton.Enable = 'on';
                app.EsitoLabel.Text = 'Dati validi: puoi analizzare e salvare.';
                app.EsitoLabel.FontColor = [0 0.5 0];
            else
                app.AnalizzaeSalvaButton.Enable = 'off';
                app.EsitoLabel.Text = messaggio;
                app.EsitoLabel.FontColor = [0.8 0 0];
            end
        end

        function [ok, messaggio] = validateAppInputs(app)
            ok = false;

            sesso = string(app.SessoDropDown.Value);
            eta = app.EtaEditField.Value;
            hgb = app.HGBEditField.Value;
            rbc = app.RBCEditField.Value;
            wbc = app.WBCEditField.Value;
            plt = app.PLTEditField.Value;
            mcv = app.MCVEditField.Value;
            mch = app.MCHEditField.Value;
            mchc = app.MCHCEditField.Value;

            if strlength(strtrim(sesso)) == 0 || ...
                    ~(sesso == "M" || sesso == "F")
                messaggio = 'Selezionare M o F per il sesso.';
                return;
            end

            % I campi sono NumericEditField: Value è già numerico.
            valori = [eta, hgb, rbc, wbc, plt, mcv, mch, mchc];

            if any(~isfinite(valori))
                messaggio = 'Tutti i campi devono contenere numeri finiti.';
                return;
            end

            if eta < 0 || eta > 120 || eta ~= floor(eta)
                messaggio = 'L''età deve essere un numero intero tra 0 e 120 anni.';
                return;
            end

            if hgb < 4 || hgb > 25
                messaggio = 'HGB deve essere compreso tra 4 e 25 g/dL.';
                return;
            end

            if rbc < 2 || rbc > 8
                messaggio = 'RBC deve essere compreso tra 2 e 8 10^6/µL.';
                return;
            end

            if wbc < 2000 || wbc > 30000
                messaggio = 'WBC deve essere compreso tra 2000 e 30000 cellule/µL.';
                return;
            end

            if plt < 50000 || plt > 700000
                messaggio = 'PLT deve essere compreso tra 50000 e 700000 piastrine/µL.';
                return;
            end

            if mcv < 50 || mcv > 150
                messaggio = 'MCV deve essere compreso tra 50 e 150 fL.';
                return;
            end

            if mch < 15 || mch > 40
                messaggio = 'MCH deve essere compreso tra 15 e 40 pg.';
                return;
            end

            if mchc < 20 || mchc > 45
                messaggio = 'MCHC deve essere compreso tra 20 e 45 g/dL.';
                return;
            end

            % Verifica anche l'ematocrito calcolato, coerentemente con il DB.
            hct = rbc * mcv / 10;
            if ~isfinite(hct) || hct <= 0 || hct >= 70
                messaggio = 'L''ematocrito calcolato è fuori dai limiti accettati dal database.';
                return;
            end

            ok = true;
            messaggio = 'Dati validi.';
        end

        % Button pushed function: AnalizzaeSalvaButton
        function AnalizzaeSalvaButtonPushed(app, event)
        [ok, messaggio] = app.validateAppInputs();

            if ~ok
                app.AnalizzaeSalvaButton.Enable = 'off';
                uialert(app.UIFigure, messaggio, 'Input non valido');
                return;
            end

            sesso = char(app.SessoDropDown.Value);
            eta = app.EtaEditField.Value;
            hgb = app.HGBEditField.Value;
            rbc = app.RBCEditField.Value;
            wbc = app.WBCEditField.Value;
            plt = app.PLTEditField.Value;
            mcv = app.MCVEditField.Value;
            mch = app.MCHEditField.Value;
            mchc = app.MCHCEditField.Value;

            hct = rbc * mcv / 10;

            % La funzione diagnostica condivisa deve essere nel MATLAB path.
            try
                [esito, anomaliaPerc, ~] = diagnostica_emocromo( ...
                    sesso, hgb, wbc, plt, mcv, mch, mchc);
            catch ME
                uialert(app.UIFigure, ...
                    ['Errore nella diagnostica: ' ME.message], ...
                    'Errore');
                return;
            end

            % Aggiorna i risultati mostrati nell'interfaccia.
            app.HCTTextArea.Value = {sprintf('HCT: %.2f %%', hct)};
            app.EsitoTextArea.Value = {char(esito)};
            app.AnomaliaTextArea.Value = ...
                {sprintf('Anomalia: %.1f%%', anomaliaPerc)};

            if string(esito) == "Valori nella Norma"
                app.EsitoLabel.Text = char(esito);
                app.EsitoLabel.FontColor = [0 0.55 0];
            else
                app.EsitoLabel.Text = char(esito);
                app.EsitoLabel.FontColor = [0.8 0 0];
            end

            conn = [];

            try
                conn = sqlite('emocromo.db');

                % Transazione: evita di lasciare record parziali in caso di errore.
                exec(conn, 'BEGIN TRANSACTION;');

                annoCorrente = year(datetime('now'));
                annoNascita = annoCorrente - eta;
                dataNascita = sprintf('%04d-01-01', annoNascita);

                % Il codice temporaneo viene sostituito dopo aver ottenuto l'ID AUTOINCREMENT.
                codiceTemp = sprintf('TMP_%d', ...
                    int64(posixtime(datetime('now')) * 1000000));

                sqlPaz = sprintf([ ...
                    'INSERT INTO PAZIENTE (Codice, Data_Nascita, Sesso) ' ...
                    'VALUES (''%s'', ''%s'', ''%s'');'], ...
                    codiceTemp, dataNascita, sesso);
                exec(conn, sqlPaz);

                resultPaz = fetch(conn, 'SELECT last_insert_rowid();');
                idPaziente = resultPaz{1, 1};

                codiceDef = sprintf('PAZ_%03d', idPaziente);
                sqlCodice = sprintf( ...
                    'UPDATE PAZIENTE SET Codice = ''%s'' WHERE ID_Paziente = %d;', ...
                    codiceDef, idPaziente);
                exec(conn, sqlCodice);

                sqlEsame = sprintf([ ...
                    'INSERT INTO ESAME_EMOCROMO ' ...
                    '(ID_Paziente, RBC, HGB, HCT, WBC, PLT, MCV, MCH, MCHC) ' ...
                    'VALUES (%d, %.15g, %.15g, %.15g, %.15g, %.15g, %.15g, %.15g, %.15g);'], ...
                    idPaziente, rbc, hgb, hct, wbc, plt, mcv, mch, mchc);
                exec(conn, sqlEsame);

                resultEsame = fetch(conn, 'SELECT last_insert_rowid();');
                idEsame = resultEsame{1, 1};

                sqlReferto = sprintf([ ...
                    'INSERT INTO REFERTO_ANALISI ' ...
                    '(ID_Esame, Esito_Diagnostico, Indice_Anomalia_Perc) ' ...
                    'VALUES (%d, ''%s'', %.15g);'], ...
                    idEsame, char(esito), anomaliaPerc);
                exec(conn, sqlReferto);

                exec(conn, 'COMMIT;');
                close(conn);

            catch ME
                if ~isempty(conn)
                    try
                        exec(conn, 'ROLLBACK;');
                    catch
                    end
                    try
                        close(conn);
                    catch
                    end
                end

                uialert(app.UIFigure, ...
                    ['Impossibile salvare i dati nel database: ' ME.message], ...
                    'Errore database');
                return;
            end

            % Aggiorna la tabella dello storico.
            app.refreshHistory();

            % Grafico: valori rispetto a riferimenti indicativi.
            valoriPct = [ ...
                (hgb / 14.0) * 100, ...
                (rbc / 4.5) * 100, ...
                (wbc / 7500.0) * 100, ...
                (plt / 250000.0) * 100];

            cla(app.UIAxes);
            bar(app.UIAxes, valoriPct, 'FaceColor', [0.2 0.4 0.8]);
            app.UIAxes.XTick = 1:4;
            app.UIAxes.XTickLabel = {'HGB', 'RBC', 'WBC', 'PLT'};
            ylabel(app.UIAxes, 'Valore rispetto al riferimento (%)');
            title(app.UIAxes, 'Parametri ematologici');
            grid(app.UIAxes, 'on');
            yline(app.UIAxes, 100, '--r', 'Riferimento (100%)', ...
                'LineWidth', 1.5);

            uialert(app.UIFigure, sprintf('Paziente salvato con successo.\nDiagnosi: %s', esito), 'Operazione completata');
        end

        % Value changed function: EtaEditField
        function EtaEditFieldValueChanged2(app, event)
           app.updateUIValidation(); 
        end

        % Value changed function: SessoDropDown
        function SessoDropDownValueChanged2(app, event)
            app.updateUIValidation();
        end

        % Value changed function: PLTEditField
        function PLTEditFieldValueChanged2(app, event)
            app.updateUIValidation();
        end

        % Value changed function: WBCEditField
        function WBCEditFieldValueChanged2(app, event)
            app.updateUIValidation();
        end

        % Value changed function: RBCEditField
        function RBCEditFieldValueChanged2(app, event)
            app.updateUIValidation();
        end

        % Value changed function: HGBEditField
        function HGBEditFieldValueChanged2(app, event)
            app.updateUIValidation();
        end

        % Value changed function: MCHCEditField
        function MCHCEditFieldValueChanged(app, event)
            app.updateUIValidation();
        end

        % Value changed function: MCHEditField
        function MCHEditFieldValueChanged(app, event)
            app.updateUIValidation();
        end

        % Value changed function: MCVEditField
        function MCVEditFieldValueChanged(app, event)
            app.updateUIValidation();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DiagnosieGraficiPanel = uipanel(app.UIFigure);
            app.DiagnosieGraficiPanel.Title = 'Diagnosi e Grafici';
            app.UIAxes = uiaxes(app.DiagnosieGraficiPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EsitoLabel = uilabel(app.DiagnosieGraficiPanel);
            app.EsitoLabel.Text = 'Esito';
            app.DatiPazienteedEsamePanel = uipanel(app.UIFigure);
            app.DatiPazienteedEsamePanel.Title = 'Dati Paziente ed Esame';
            app.SessoDropDownLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.SessoDropDownLabel.Text = 'Sesso';
            app.SessoDropDown = uidropdown(app.DatiPazienteedEsamePanel);
            app.SessoDropDown.Items = {'M', 'F'};
            app.SessoDropDown.ValueChangedFcn = createCallbackFcn(app, @SessoDropDownValueChanged2, true);
            app.SessoDropDown.Value = 'M';
            app.HGBEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.HGBEditFieldLabel.Text = 'HGB';
            app.HGBEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.HGBEditField.ValueChangedFcn = createCallbackFcn(app, @HGBEditFieldValueChanged2, true);
            app.HGBEditField.Value = 14.5;
            app.RBCEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.RBCEditFieldLabel.Text = 'RBC';
            app.RBCEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.RBCEditField.ValueChangedFcn = createCallbackFcn(app, @RBCEditFieldValueChanged2, true);
            app.RBCEditField.Value = 5;
            app.WBCEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.WBCEditFieldLabel.Text = 'WBC';
            app.WBCEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.WBCEditField.ValueChangedFcn = createCallbackFcn(app, @WBCEditFieldValueChanged2, true);
            app.WBCEditField.Value = 7500;
            app.PLTEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.PLTEditFieldLabel.Text = 'PLT';
            app.PLTEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.PLTEditField.ValueChangedFcn = createCallbackFcn(app, @PLTEditFieldValueChanged2, true);
            app.PLTEditField.Value = 250000;
            app.AnalizzaeSalvaButton = uibutton(app.DatiPazienteedEsamePanel, 'push');
            app.AnalizzaeSalvaButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizzaeSalvaButtonPushed, true);
            app.AnalizzaeSalvaButton.Text = 'Analizza e Salva';
            app.EtaEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.EtaEditFieldLabel.Text = 'Eta';
            app.EtaEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.EtaEditField.ValueChangedFcn = createCallbackFcn(app, @EtaEditFieldValueChanged2, true);
            app.EtaEditField.Value = 35;
            app.MCHEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.MCHEditFieldLabel.Text = 'MCH';
            app.MCHEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.MCHEditField.ValueChangedFcn = createCallbackFcn(app, @MCHEditFieldValueChanged, true);
            app.MCHEditField.Value = 30;
            app.MCVEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.MCVEditFieldLabel.Text = 'MCV';
            app.MCVEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.MCVEditField.ValueChangedFcn = createCallbackFcn(app, @MCVEditFieldValueChanged, true);
            app.MCVEditField.Value = 90;
            app.MCHCEditFieldLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.MCHCEditFieldLabel.Text = 'MCHC';
            app.MCHCEditField = uieditfield(app.DatiPazienteedEsamePanel, 'numeric');
            app.MCHCEditField.ValueChangedFcn = createCallbackFcn(app, @MCHCEditFieldValueChanged, true);
            app.MCHCEditField.Value = 33;
            app.HGBUnitLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.HGBUnitLabel.Text = 'HGBUnit';
            app.RBCUnitLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.RBCUnitLabel.Text = 'RBCUnit';
            app.WBCUnitLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.WBCUnitLabel.Text = 'WBCUnit';
            app.PLTUnitLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.PLTUnitLabel.Text = 'PLTUnit';
            app.EtaUnitLabel = uilabel(app.DatiPazienteedEsamePanel);
            app.EtaUnitLabel.Text = 'EtaUnit';
            app.StoricoSQLitePanel = uipanel(app.UIFigure);
            app.StoricoSQLitePanel.Title = 'Storico SQLite';
            app.UITable = uitable(app.StoricoSQLitePanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.MCHUnitLabel = uilabel(app.UIFigure);
            app.MCHUnitLabel.Text = 'MCHUnit';
            app.MCHCUnitLabel = uilabel(app.UIFigure);
            app.MCHCUnitLabel.Text = 'MCHCUnit';
            app.MCVUnitLabel = uilabel(app.UIFigure);
            app.MCVUnitLabel.Text = 'MCVUnit';
            app.AnomaliaTextArea = uitextarea(app.UIFigure);
            app.HCTTextArea = uitextarea(app.UIFigure);
            app.EsitoTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EmocromoAnalyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end